classdef CheckOut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AddCommodity      matlab.ui.control.Button
        ExitButton        matlab.ui.control.Button
        ManagerWelcome    matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Name              matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        CurrentInventory  matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        Number            matlab.ui.control.NumericEditField
        UITable           matlab.ui.control.Table
        Label_5           matlab.ui.control.Label
        TotalPrice        matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        Pay               matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        Change            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        mainapp % Description
        totalprice
    end
    
    methods (Access = private)
        
        function [exist,results,index] = findPrice(app)
            results = 0;
            name = app.Name.Value;
            load Commodity.mat;
            len = length(CName);
            exist = false;
            index = 0;
            for i = 1:len
                if length(char(CName{i,1})) == length(name)                
                    if CName{i,1} == name
                        exist = true;
                        app.CurrentInventory.Value = CInventory{i,1}; 
                        results = CPrice{i,1};
                        index = i;
                        break;
                    end
                end
            end
            if ~exist
                warndlg("您输入的商品不存在。请重新输入！");
            end               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.mainapp = main;
            app.totalprice = 0.0;
        end

        % Button pushed function: AddCommodity
        function AddCommodityButtonPushed(app, event)
            
            [exist,results,index] = app.findPrice();
            price = 0;
            if exist
                price = results;
            else 
                return;
            end
            load Commodity.mat
            if CInventory{index,1} < app.Number.Value
                warndlg("购买数量不可能大于库存数量，填写错误！！！");
                return;
            end
            
            tempdata=app.UITable.Data;
            if isempty(tempdata)
                tempdata = {app.Name.Value,price,app.Number.Value};
                app.UITable.Data = tempdata;
                
            else
                sameind=find(contains(tempdata(:,1),app.Name.Value));
                if isempty(sameind)
                    tempdata=[tempdata;{app.Name.Value,price,app.Number.Value}];
                    app.UITable.Data=tempdata;
                else
                    tempdata{sameind,3}=tempdata{sameind,3} + app.Number.Value;
                    app.UITable.Data=tempdata;
                end
            end
            
            CInventory{index,1} = CInventory{index,1} - app.Number.Value;
            if CInventory{index,1} < 10
                warndlg("该商品库存过少，您需要提醒经理进货！");
            end
            save Commodity CName CPrice CInventory;
            app.totalprice = app.totalprice + price * app.Number.Value;
            app.TotalPrice.Value = app.totalprice;
            if app.Pay.Value~= 0 
                app.Change.Value = app.Pay.Value - app.TotalPrice.Value;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: Pay
        function PayValueChanged(app, event)
            value = app.Pay.Value;
            app.Change.Value = value - app.TotalPrice.Value;
        end

        % Value changed function: TotalPrice
        function TotalPriceValueChanged(app, event)
            value = app.TotalPrice.Value;
            app.Change.Value = app.Pay.Value - value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddCommodity = uibutton(app.UIFigure, 'push');
            app.AddCommodity.ButtonPushedFcn = createCallbackFcn(app, @AddCommodityButtonPushed, true);
            app.AddCommodity.Text = '添加商品';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '退出';
            app.ManagerWelcome = uilabel(app.UIFigure);
            app.ManagerWelcome.Text = '欢迎使用爱坤超市系统';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '操作完记得点击退出哦';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '商品名称';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '当前库存';
            app.CurrentInventory = uieditfield(app.UIFigure, 'numeric');
            app.CurrentInventory.Limits = [0 Inf];
            app.CurrentInventory.ValueDisplayFormat = '%.0f';
            app.CurrentInventory.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '购买数量';
            app.Number = uieditfield(app.UIFigure, 'numeric');
            app.Number.ValueDisplayFormat = '%.0f';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'商品名称'; '价格'; '购买数量'};
            app.UITable.RowName = {};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '商品总价';
            app.TotalPrice = uieditfield(app.UIFigure, 'numeric');
            app.TotalPrice.ValueDisplayFormat = '%.2f';
            app.TotalPrice.ValueChangedFcn = createCallbackFcn(app, @TotalPriceValueChanged, true);
            app.TotalPrice.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '付款';
            app.Pay = uieditfield(app.UIFigure, 'numeric');
            app.Pay.ValueDisplayFormat = '%.2f';
            app.Pay.ValueChangedFcn = createCallbackFcn(app, @PayValueChanged, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '找零';
            app.Change = uieditfield(app.UIFigure, 'numeric');
            app.Change.ValueDisplayFormat = '%.2f';
            app.Change.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckOut(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end